classdef DWIfusion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DWIFusionUIFigure               matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadprocessedMenu               matlab.ui.container.Menu
        LoadrawMenu                     matlab.ui.container.Menu
        ExportresultsMenu               matlab.ui.container.Menu
        ToNiftiMenu                     matlab.ui.container.Menu
        TomatfileMenu                   matlab.ui.container.Menu
        ProcessMenu                     matlab.ui.container.Menu
        ExtractsignalforAALregionsMenu  matlab.ui.container.Menu
        SmoothingMenu                   matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        AppGrid                         matlab.ui.container.GridLayout
        TopLeft                         matlab.ui.container.TabGroup
        DataTab                         matlab.ui.container.Tab
        CalculateButton                 matlab.ui.control.Button
        bsplitvalueEditField            matlab.ui.control.NumericEditField
        bsplitvalueEditFieldLabel       matlab.ui.control.Label
        SizeEditFieldLabel              matlab.ui.control.Label
        SizeEditField                   matlab.ui.control.EditField
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        DWIsEditFieldLabel              matlab.ui.control.Label
        DWIsEditField                   matlab.ui.control.EditField
        AnalysisTab                     matlab.ui.container.Tab
        PlottingPanel                   matlab.ui.container.Panel
        ShowresidualsButton             matlab.ui.control.Button
        TogglepredictionButton          matlab.ui.control.StateButton
        ShowsegmentedButton             matlab.ui.control.StateButton
        TogglesignalButton              matlab.ui.control.StateButton
        ShowsignalplotButton            matlab.ui.control.Button
        ShowpredictionplotButton        matlab.ui.control.Button
        DEditField                      matlab.ui.control.NumericEditField
        DEditFieldLabel                 matlab.ui.control.Label
        DstarEditField                  matlab.ui.control.NumericEditField
        DstarEditFieldLabel             matlab.ui.control.Label
        fEditField                      matlab.ui.control.NumericEditField
        fEditFieldLabel                 matlab.ui.control.Label
        S0EditField                     matlab.ui.control.NumericEditField
        S0EditFieldLabel                matlab.ui.control.Label
        RegionDropDown                  matlab.ui.control.DropDown
        RegionDropDownLabel             matlab.ui.control.Label
        HighlightCheckBox               matlab.ui.control.CheckBox
        BottomRight                     matlab.ui.container.GridLayout
        VolumeSpinner                   matlab.ui.control.Spinner
        VolumeSpinnerLabel              matlab.ui.control.Label
        SliceSlider                     matlab.ui.control.Slider
        SliceSliderLabel                matlab.ui.control.Label
        BottomLeft                      matlab.ui.container.GridLayout
        DetailsTextAreaLabel            matlab.ui.control.Label
        DetailsTextArea                 matlab.ui.control.TextArea
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        dataname % Name of image data file
        path     % path to dataname
        data     % loaded images
        datainfo % Nifti header of images
        atlased  % data segmented according to atlas
        bvals    % b values for images
        I        % Displayed image
        results  % variable for storing results
        atlas = niftiread('ROI_MNI_V7.nii') % Read AAL
        atlasROI = 1 % current number for AAL ROI
    end

    methods (Access = private)

        function [] = updateui(app)

            app.DWIsEditField.Value=app.dataname;
            app.data=niftiread(strcat(app.path,app.dataname));
            app.datainfo=niftiinfo(strcat(app.path,app.dataname));
            try
                name=split(app.dataname,".");
                app.bvals=load(strcat(app.path,name(1),".bval"));
            catch
                waitfor(msgbox("Bvals not found! Please select bvals manually","Warning",'warn'));

                [bvalsname,bvalspath] = uigetfile('*.*');
                app.bvals=load(strcat(bvalspath,bvalsname));
            end
            dimensions=size(app.data);
            app.SizeEditField.Value=(num2str(size(app.data)));

            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            app.SliceSlider.Value=round(dimensions(3)/2);
            app.draw(app.SliceSlider.Value);
            app.SliceSlider.Limits=[1 size(app.data,3)];
            app.SliceSlider.MajorTicks=[1 5:5:size(app.data,3)];
            app.SliceSlider.MajorTickLabels=string([1 5:5:size(app.data,3)]);
            app.SliceSlider.MajorTickLabels(2:2:end)={' '};
            app.SliceSlider.MinorTicks=1:size(app.data,3);
            nbvals=numel(app.bvals);
            app.VolumeSpinner.Limits=[1 nbvals];
            app.SliceSlider.Visible=true;
            app.SliceSliderLabel.Visible=true;
            app.VolumeSpinner.Visible=true;
            app.VolumeSpinnerLabel.Visible=true;
        end

        function [] = draw(app,slice)
            brain_i=squeeze(app.data(:,:,slice,app.VolumeSpinner.Value));
            maxintensity=max(brain_i,[], 'all');
            brain_i=brain_i/maxintensity;
            if app.HighlightCheckBox.Value==true
                app.atlasROI=str2double(app.RegionDropDown.Value);
                mask=brain_i;
                mask(squeeze(app.atlas(:,:,slice)==app.atlasROI))=1;
                brain_i=cat(3,mask,brain_i,brain_i);
            end
            app.I = imshow(brain_i, [0 1],...
                'Parent', app.UIAxes);
            app.UIAxes.XLim = [0 app.I.XData(2)];
            app.UIAxes.YLim = [0 app.I.YData(2)];

        end

        function [] = updateDetails(app,text)
            app.DetailsTextArea.Value=[app.DetailsTextArea.Value; text];
        end

        function [] = clearDetails(app)
            app.DetailsTextArea.Value = [];
        end


        function [] = signalPlot(app)
            title("Signal plot for " + app.RegionDropDown.Items(...
                find(str2double(app.RegionDropDown.Value)==str2double(app.RegionDropDown.ItemsData))) +" region");
            hold on
            plot(app.bvals,app.atlased(app.atlasROI,:),'*','DisplayName',"Signal");
            legend('Signal');
        end

        function [] = predictionPlot(app)
            x=linspace(min(app.bvals),max(app.bvals),100);
            if app.ShowsegmentedButton.Value==true
                Stissue=(1-app.results(app.atlasROI,2))*app.results(app.atlasROI,1)...
                    *exp(-x*app.results(app.atlasROI,4));
                Sblood=app.results(app.atlasROI,1)*app.results(app.atlasROI,2)...
                    *exp(-x*app.results(app.atlasROI,3));
                plot(x,Stissue,'DisplayName',"Tissue prediction");
                plot(x,Stissue+Sblood,'DisplayName',"Blood prediction");
            else
                S=app.results(app.atlasROI,1)*app.results(app.atlasROI,2)...
                    *exp(-x*app.results(app.atlasROI,3))...
                    +(1-app.results(app.atlasROI,2))*app.results(app.atlasROI,1)...
                    *exp(-x*app.results(app.atlasROI,4));
                plot(x,S,'DisplayName',"Prediction");
            end


        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all;
        end

        % Menu selected function: LoadrawMenu
        function LoadrawMenuSelected(app, event)
            [app.dataname,app.path] = uigetfile('*.*');
            %             try
            app.updateui();
            %             catch
            %                 app.updateDetails("Error loading data");
            %                 return
            %             end
            app.TopLeft.Visible=true;
            app.updateDetails("Succesfully loaded data");
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            value = app.MethodDropDown.Value;
            if(isequal(value,'1step'))
                app.bsplitvalueEditField.Enable=false;
                app.bsplitvalueEditFieldLabel.Enable=false;
            else
                app.bsplitvalueEditField.Enable=true;
                app.bsplitvalueEditFieldLabel.Enable=true;
            end
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            value = app.SliceSlider.Value;
            app.SliceSlider.Value=round(value,0);
            app.draw(app.SliceSlider.Value);
        end

        % Value changing function: SliceSlider
        function SliceSliderValueChanging(app, event)
            changingValue = event.Value;
            app.draw(round(changingValue,0));
        end

        % Menu selected function: ExtractsignalforAALregionsMenu
        function ExtractsignalforAALregionsMenuSelected(app, event)
            if (size(app.data,1:3)==size(app.atlas))
            app.atlased=atlsegment(app.data,app.atlas);
            app.updateDetails("Succesfully extracted AAL segmented data");
            else
            app.updateDetails("You have to perform regridding first!");
            end
        end

        % Value changed function: VolumeSpinner
        function VolumeSpinnerValueChanged(app, event)
            app.draw(app.SliceSlider.Value);
        end

        % Value changed function: HighlightCheckBox
        function HighlightCheckBoxValueChanged(app, event)
            app.draw(app.SliceSlider.Value);
        end

        % Value changed function: RegionDropDown
        function RegionDropDownValueChanged(app, event)
            app.atlasROI=str2double(app.RegionDropDown.Value);
            app.draw(app.SliceSlider.Value);
            if numel(app.results)>0
                app.S0EditField.Value=app.results(app.atlasROI,1);
                app.fEditField.Value=app.results(app.atlasROI,2);
                app.DstarEditField.Value=app.results(app.atlasROI,3);
                app.DEditField.Value=app.results(app.atlasROI,4);
                if app.TogglesignalButton.Value==true
                    figure(app.atlasROI)
                    app.signalPlot();
                end
                if app.TogglepredictionButton.Value==true
                    figure(app.atlasROI)
                    app.predictionPlot();
                end
            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.updateDetails("Calculating, please wait")
            if numel(app.atlased)>0
                app.results=get_ivim_v4(app.bvals',app.atlased,method=app.MethodDropDown.Value,bsplit=app.bsplitvalueEditField.Value,mask='atlas');
            else
                app.results=get_ivim_v4(app.bvals',app.data,method=app.MethodDropDown.Value,bsplit=app.bsplitvalueEditField.Value);
            end
            app.updateDetails("Calculated")
            app.ShowpredictionplotButton.Enable=true;
        end

        % Button pushed function: ShowsignalplotButton
        function ShowSignalPlotButtonPushed(app, event)
            figure(str2double(app.RegionDropDown.Value))
            app.signalPlot();
        end

        % Button pushed function: ShowpredictionplotButton
        function ShowpredictionplotButtonPushed(app, event)
            figure(str2double(app.RegionDropDown.Value))
            app.predictionPlot();
        end

        % Button down function: AnalysisTab
        function AnalysisTabButtonDown(app, event)
            x=str2double(app.RegionDropDown.Value);
            if numel(app.results)>0
                app.S0EditField.Value=app.results(x,1);
                app.fEditField.Value=app.results(x,2);
                app.DstarEditField.Value=app.results(x,3);
                app.DEditField.Value=app.results(x,4);
            end
        end

        % Button pushed function: ShowresidualsButton
        function ShowresidualsButtonPushed(app, event)
            x=app.bvals;
            S=app.results(app.atlasROI,1)*app.results(app.atlasROI,2)...
                    *exp(-x*app.results(app.atlasROI,3))...
                    +(1-app.results(app.atlasROI,2))*app.results(app.atlasROI,1)...
                    *exp(-x*app.results(app.atlasROI,4));
            figure(200+str2double(app.RegionDropDown.Value))
            boxplot(S-app.atlased(app.atlasROI,:),app.bvals);
        end

        % Menu selected function: TomatfileMenu
        function TomatfileMenuSelected(app, event)
            app.updateDetails("Exporting data to *.mat")
            savepath=uigetdir();
            filename=inputdlg("Enter filename","Filename",[1 35],strcat(app.dataname,"_IVIMresults.mat"));
            IVIM_map=app.results;
            save(strcat(savepath,'/',filename{1}),'IVIM_map');
        end

        % Menu selected function: ToNiftiMenu
        function ToNiftiMenuSelected(app, event)
            app.updateDetails("Exporting data to NIfTI")
            savepath=uigetdir();
            filename=inputdlg("Enter filename","Filename",[1 35],strcat(app.dataname,"_IVIMresults.nii"));
            app.datainfo.ImageSize=size(app.results);
            app.datainfo.Datatype=class(app.results);
            niftiwrite(app.results,strcat(savepath,'/',filename{1}),app.datainfo);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DWIFusionUIFigure = uifigure('Visible', 'off');
            app.DWIFusionUIFigure.Name = 'DWIFusion';
            app.FileMenu = uimenu(app.DWIFusionUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadprocessedMenu = uimenu(app.FileMenu);
            app.LoadprocessedMenu.Text = 'Load processed';
            app.LoadrawMenu = uimenu(app.FileMenu);
            app.LoadrawMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadrawMenuSelected, true);
            app.LoadrawMenu.Text = 'Load raw';
            app.ExportresultsMenu = uimenu(app.FileMenu);
            app.ExportresultsMenu.Text = 'Export results';
            app.ToNiftiMenu = uimenu(app.ExportresultsMenu);
            app.ToNiftiMenu.MenuSelectedFcn = createCallbackFcn(app, @ToNiftiMenuSelected, true);
            app.ToNiftiMenu.Text = 'To Nifti';
            app.TomatfileMenu = uimenu(app.ExportresultsMenu);
            app.TomatfileMenu.MenuSelectedFcn = createCallbackFcn(app, @TomatfileMenuSelected, true);
            app.TomatfileMenu.Text = 'To *.mat file';
            app.ProcessMenu = uimenu(app.DWIFusionUIFigure);
            app.ProcessMenu.Text = 'Process';
            app.ExtractsignalforAALregionsMenu = uimenu(app.ProcessMenu);
            app.ExtractsignalforAALregionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtractsignalforAALregionsMenuSelected, true);
            app.ExtractsignalforAALregionsMenu.Text = 'Extract signal for AAL regions';
            app.SmoothingMenu = uimenu(app.ProcessMenu);
            app.SmoothingMenu.Text = 'Smoothing';
            app.Menu = uimenu(app.ProcessMenu);
            app.Menu.Text = 'Menu';
            app.AppGrid = uigridlayout(app.DWIFusionUIFigure);
            app.UIAxes = uiaxes(app.AppGrid);
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.AppGrid);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.BottomLeft = uigridlayout(app.AppGrid);
            app.DetailsTextArea = uitextarea(app.BottomLeft);
            app.DetailsTextArea.Editable = 'off';
            app.DetailsTextArea.Value = {'Welcome to DWIFusion, please load data to begin.'};
            app.DetailsTextAreaLabel = uilabel(app.BottomLeft);
            app.DetailsTextAreaLabel.Text = 'Details';
            app.BottomRight = uigridlayout(app.AppGrid);
            app.SliceSliderLabel = uilabel(app.BottomRight);
            app.SliceSliderLabel.Visible = 'off';
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.BottomRight);
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.MajorTickLabels = {};
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.ValueChangingFcn = createCallbackFcn(app, @SliceSliderValueChanging, true);
            app.SliceSlider.MinorTicks = [];
            app.SliceSlider.Visible = 'off';
            app.VolumeSpinnerLabel = uilabel(app.BottomRight);
            app.VolumeSpinnerLabel.Visible = 'off';
            app.VolumeSpinnerLabel.Text = 'Volume';
            app.VolumeSpinner = uispinner(app.BottomRight);
            app.VolumeSpinner.Limits = [1 100];
            app.VolumeSpinner.RoundFractionalValues = 'on';
            app.VolumeSpinner.ValueChangedFcn = createCallbackFcn(app, @VolumeSpinnerValueChanged, true);
            app.VolumeSpinner.Visible = 'off';
            app.VolumeSpinner.Value = 1;
            app.TopLeft = uitabgroup(app.AppGrid);
            app.TopLeft.Visible = 'off';
            app.DataTab = uitab(app.TopLeft);
            app.DataTab.Title = 'Data';
            app.DWIsEditField = uieditfield(app.DataTab, 'text');
            app.DWIsEditField.Editable = 'off';
            app.DWIsEditFieldLabel = uilabel(app.DataTab);
            app.DWIsEditFieldLabel.Text = 'DWIs';
            app.MethodDropDown = uidropdown(app.DataTab);
            app.MethodDropDown.Items = {'1step', 'segmented', 'grid'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = '1step';
            app.MethodDropDownLabel = uilabel(app.DataTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.SizeEditField = uieditfield(app.DataTab, 'text');
            app.SizeEditField.Editable = 'off';
            app.SizeEditFieldLabel = uilabel(app.DataTab);
            app.SizeEditFieldLabel.Text = 'Size';
            app.bsplitvalueEditFieldLabel = uilabel(app.DataTab);
            app.bsplitvalueEditFieldLabel.Enable = 'off';
            app.bsplitvalueEditFieldLabel.Text = 'b-split value';
            app.bsplitvalueEditField = uieditfield(app.DataTab, 'numeric');
            app.bsplitvalueEditField.Enable = 'off';
            app.bsplitvalueEditField.Value = 250;
            app.CalculateButton = uibutton(app.DataTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AnalysisTab = uitab(app.TopLeft);
            app.AnalysisTab.Title = 'Analysis';
            app.AnalysisTab.ButtonDownFcn = createCallbackFcn(app, @AnalysisTabButtonDown, true);
            app.HighlightCheckBox = uicheckbox(app.AnalysisTab);
            app.HighlightCheckBox.ValueChangedFcn = createCallbackFcn(app, @HighlightCheckBoxValueChanged, true);
            app.HighlightCheckBox.Text = 'Highlight';
            app.RegionDropDownLabel = uilabel(app.AnalysisTab);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.AnalysisTab);
            app.RegionDropDown.Items = {'Precentral_L', 'Precentral_R', 'Frontal_Sup_2_L', 'Frontal_Sup_2_R', 'Frontal_Mid_2_L', 'Frontal_Mid_2_R', 'Frontal_Inf_Oper_L', 'Frontal_Inf_Oper_R', 'Frontal_Inf_Tri_L', 'Frontal_Inf_Tri_R', 'Frontal_Inf_Orb_2_L', 'Frontal_Inf_Orb_2_R', 'Rolandic_Oper_L', 'Rolandic_Oper_R', 'Supp_Motor_Area_L', 'Supp_Motor_Area_R', 'Olfactory_L', 'Olfactory_R', 'Frontal_Sup_Medial_L', 'Frontal_Sup_Medial_R', 'Frontal_Med_Orb_L', 'Frontal_Med_Orb_R', 'Rectus_L', 'Rectus_R', 'OFCmed_L', 'OFCmed_R', 'OFCant_L', 'OFCant_R', 'OFCpost_L', 'OFCpost_R', 'OFClat_L', 'OFClat_R', 'Insula_L', 'Insula_R', 'Cingulate_Mid_L', 'Cingulate_Mid_R', 'Cingulate_Post_L', 'Cingulate_Post_R', 'Hippocampus_L', 'Hippocampus_R', 'ParaHippocampal_L', 'ParaHippocampal_R', 'Amygdala_L', 'Amygdala_R', 'Calcarine_L', 'Calcarine_R', 'Cuneus_L', 'Cuneus_R', 'Lingual_L', 'Lingual_R', 'Occipital_Sup_L', 'Occipital_Sup_R', 'Occipital_Mid_L', 'Occipital_Mid_R', 'Occipital_Inf_L', 'Occipital_Inf_R', 'Fusiform_L', 'Fusiform_R', 'Postcentral_L', 'Postcentral_R', 'Parietal_Sup_L', 'Parietal_Sup_R', 'Parietal_Inf_L', 'Parietal_Inf_R', 'SupraMarginal_L', 'SupraMarginal_R', 'Angular_L', 'Angular_R', 'Precuneus_L', 'Precuneus_R', 'Paracentral_Lobule_L', 'Paracentral_Lobule_R', 'Caudate_L', 'Caudate_R', 'Putamen_L', 'Putamen_R', 'Pallidum_L', 'Pallidum_R', 'Heschl_L', 'Heschl_R', 'Temporal_Sup_L', 'Temporal_Sup_R', 'Temporal_Pole_Sup_L', 'Temporal_Pole_Sup_R', 'Temporal_Mid_L', 'Temporal_Mid_R', 'Temporal_Pole_Mid_L', 'Temporal_Pole_Mid_R', 'Temporal_Inf_L', 'Temporal_Inf_R', 'Cerebellum_Crus1_L', 'Cerebellum_Crus1_R', 'Cerebellum_Crus2_L', 'Cerebellum_Crus2_R', 'Cerebellum_3_L', 'Cerebellum_3_R', 'Cerebellum_4_5_L', 'Cerebellum_4_5_R', 'Cerebellum_6_L', 'Cerebellum_6_R', 'Cerebellum_7b_L', 'Cerebellum_7b_R', 'Cerebellum_8_L', 'Cerebellum_8_R', 'Cerebellum_9_L', 'Cerebellum_9_R', 'Cerebellum_10_L', 'Cerebellum_10_R', 'Vermis_1_2', 'Vermis_3', 'Vermis_4_5', 'Vermis_6', 'Vermis_7', 'Vermis_8', 'Vermis_9', 'Vermis_10', 'Thal_AV_L', 'Thal_AV_R', 'Thal_LP_L', 'Thal_LP_R', 'Thal_VA_L', 'Thal_VA_R', 'Thal_VL_L', 'Thal_VL_R', 'Thal_VPL_L', 'Thal_VPL_R', 'Thal_IL_L', 'Thal_IL_R', 'Thal_Re_L', 'Thal_Re_R', 'Thal_MDm_L', 'Thal_MDm_R', 'Thal_MDl_L', 'Thal_MDl_R', 'Thal_LGN_L', 'Thal_LGN_R', 'Thal_MGN_L', 'Thal_MGN_R', 'Thal_PuI_L', 'Thal_PuI_R', 'Thal_PuM_L', 'Thal_PuM_R', 'Thal_PuA_L', 'Thal_PuA_R', 'Thal_PuL_L', 'Thal_PuL_R', 'ACC_sub_L', 'ACC_sub_R', 'ACC_pre_L', 'ACC_pre_R', 'ACC_sup_L', 'ACC_sup_R', 'N_Acc_L', 'N_Acc_R', 'VTA_L', 'VTA_R', 'SN_pc_L', 'SN_pc_R', 'SN_pr_L', 'SN_pr_R', 'Red_N_L', 'Red_N_R', 'LC_L', 'LC_R', 'Raphe_D', 'Raphe_M'};
            app.RegionDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60', '61', '62', '63', '64', '65', '66', '67', '68', '69', '70', '71', '72', '73', '74', '75', '76', '77', '78', '79', '80', '83', '84', '85', '86', '87', '88', '89', '90', '91', '92', '93', '94', '95', '96', '97', '98', '99', '100', '101', '102', '103', '104', '105', '106', '107', '108', '109', '110', '111', '112', '113', '114', '115', '116', '117', '118', '119', '120', '121', '122', '123', '124', '125', '126', '127', '128', '129', '130', '131', '132', '133', '134', '135', '136', '137', '138', '139', '140', '141', '142', '143', '144', '145', '146', '147', '148', '149', '150', '151', '152', '153', '154', '155', '156', '157', '158', '159', '160', '161', '162', '163', '164', '165', '166', '167', '168', '169', '170'};
            app.RegionDropDown.ValueChangedFcn = createCallbackFcn(app, @RegionDropDownValueChanged, true);
            app.RegionDropDown.Value = '1';
            app.S0EditFieldLabel = uilabel(app.AnalysisTab);
            app.S0EditFieldLabel.Text = 'S0';
            app.S0EditField = uieditfield(app.AnalysisTab, 'numeric');
            app.fEditFieldLabel = uilabel(app.AnalysisTab);
            app.fEditFieldLabel.Text = 'f';
            app.fEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.DstarEditFieldLabel = uilabel(app.AnalysisTab);
            app.DstarEditFieldLabel.Text = 'Dstar';
            app.DstarEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.DEditFieldLabel = uilabel(app.AnalysisTab);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.PlottingPanel = uipanel(app.AnalysisTab);
            app.PlottingPanel.Title = 'Plotting';
            app.ShowpredictionplotButton = uibutton(app.PlottingPanel, 'push');
            app.ShowpredictionplotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowpredictionplotButtonPushed, true);
            app.ShowpredictionplotButton.Enable = 'off';
            app.ShowpredictionplotButton.Text = 'Show prediction plot';
            app.ShowsignalplotButton = uibutton(app.PlottingPanel, 'push');
            app.ShowsignalplotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowSignalPlotButtonPushed, true);
            app.ShowsignalplotButton.Text = 'Show signal plot';
            app.TogglesignalButton = uibutton(app.PlottingPanel, 'state');
            app.TogglesignalButton.Text = 'Toggle signal';
            app.ShowsegmentedButton = uibutton(app.PlottingPanel, 'state');
            app.ShowsegmentedButton.Text = 'Show segmented';
            app.TogglepredictionButton = uibutton(app.PlottingPanel, 'state');
            app.TogglepredictionButton.Text = 'Toggle prediction';
            app.ShowresidualsButton = uibutton(app.PlottingPanel, 'push');
            app.ShowresidualsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowresidualsButtonPushed, true);
            app.ShowresidualsButton.Text = 'Show residuals';
            % Show the figure after all components are created
            app.DWIFusionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DWIfusion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DWIFusionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DWIFusionUIFigure)
        end
    end
end